classdef two_tank_lab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        LED2Label                   matlab.ui.control.Label
        LED1Label                   matlab.ui.control.Label
        VariableDropDown            matlab.ui.control.DropDown
        VariableDropDownLabel       matlab.ui.control.Label
        LED2Lamp                    matlab.ui.control.Lamp
        LED1Lamp                    matlab.ui.control.Lamp
        FileLabel                   matlab.ui.control.Label
        SendFileButton              matlab.ui.control.Button
        SystemInfo                  matlab.ui.control.Label
        UserInfo                    matlab.ui.control.Label
        ControlpanelPanel           matlab.ui.container.Panel
        StopButton                  matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        EmergButton                 matlab.ui.control.Button
        Panel                       matlab.ui.container.Panel
        LaboratorioRemotoMaIELabel  matlab.ui.control.Label
        Image                       matlab.ui.control.Image
        UploadButton                matlab.ui.control.Button
        DownloadButton              matlab.ui.control.Button
        ConnectRPi                  matlab.ui.control.StateButton
        ConnectLabel                matlab.ui.control.Label
        PLCLabel                    matlab.ui.control.Label
        IniciarPLCButton            matlab.ui.control.StateButton
        UIAxes                      matlab.ui.control.UIAxes
    end

    properties (Access = private)
        user % username
        myraspi % raspberry pi object
        modbusServer % modbus server object
        Timer % timer send user connection status
        StartReg = 1025 % register for START button
        StopReg = 1026 % register for STOP button
        EmergReg = 1027 % register for EMERGENCY button
        serialPort3 = 'COM5' % serial port to notify raspberry pi availability
        serialDevice3 = '' % serial port object
        tcpPort1 = 31000 % tcp port for sending client's commands (PCL program)
        tcpClient1 % tcp client 1
        tcpPort2 = 5555 % tcp port for monitoring system variables
        tcpClient2 % tcp client 2
        RPiAvailable = '0'
        RPiConnection = '0' % '1' if raspberry pi connection is stableshed
        uploadedFile % uploaded file
        uploadedPath = '' % path of uploaded file
        PLCRunning = '0' % '1' if PLC is running
        dataSize = 2 % number of readings to be made before updating GUI
        FileSent = '0' % '1' if file has been sent to the raspberry pi
        PlotLine
        PlotLine2
        led1Data
        led2Data
        selectedVar = 'LED 1'
        xlen
    end
    
    methods (Access = private)
        %% Callbacks
        function readSystemVariables(app, src, ~)
            % read variables from raspberry pi through modbus protocol
            if src.NumBytesAvailable>0
                try
                    data = read(src, app.dataSize*2, 'double');
                    % Update figures
                    app.led1Data = app.updateLinePlot(app.led1Data, data(1:app.dataSize));
                    app.led2Data = app.updateLinePlot(app.led2Data, data(app.dataSize+1:2*app.dataSize));
                    if strcmp(app.selectedVar, 'LED 1')
                        app.PlotLine.YData = app.led1Data;
                    else
                        app.PlotLine.YData = app.led2Data;
                    end
                    % Update LEDs
                    led1 = data(1);
                    led2 = data(app.dataSize+1);
                    app.turnOnOffLED(app.LED1Lamp, led1, 'red');
                    app.turnOnOffLED(app.LED2Lamp, led2, 'blue');
                catch me
                    app.SystemInfo.Text = me.message;
                end
            end
        end
        function updateRPiStatus(app, src, ~)
            % Enable/disable RPi connection button when raspberry pi
            % availability changes
            if src.NumBytesAvailable>0
                msg = read(src, src.NumBytesAvailable, 'char');
                msg = split(msg, '|');
                app.RPiAvailable = msg{1};
                if strcmp(app.RPiAvailable, '1')&&(~strcmp(msg{2}, app.user)) % An user is connected to RPi
                    app.ConnectRPi.Enable = 'off';
                else % An user has disconnected from RPi
                    app.ConnectRPi.Enable = 'on';
                end
            end
        end
        %% Timer functions
        function TimerFcn(app, ~, ~)
            % send user connection confirmation through tcp port
            writeline(app.tcpClient1,  ['connected', '|', '-']);
        end
        %% Helper functions 
        function turnOnOffLED(app, led, state, color)
            if state==1
                led.Color = color;
            else
                led.Color = 'black';
            end
        end
        function updateControlLEDs(app, colors)
            app.StartButton.BackgroundColor = colors{1};
            app.StopButton.BackgroundColor = colors{2};
            app.EmergButton.BackgroundColor = colors{3};
        end
        function line = updateLinePlot(app, line, data)
              line = circshift(line, app.dataSize);
              line(1:app.dataSize) = data;
        end
        function enableControlButtons(app, state)
            app.StartButton.Enable = state;
            app.StopButton.Enable = state;
            app.EmergButton.Enable = state;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Get user information
            try
                log = compiler.UserInfo();
                app.user = log.UserID;
                app.UserInfo.Text = strcat('Usuario:', {' '}, app.user);
            catch me
                app.SystemInfo.Text = me.message;
            end
            % Plots
            disableDefaultInteractivity(app.UIAxes);
            app.UIAxes.Toolbar.Visible = 'off';
                % Configure x- and y- axis
            app.xlen = 500;
            app.led1Data = zeros(1, app.xlen+1);
            app.led2Data = zeros(1, app.xlen+1);
            app.UIAxes.XLim = [0 app.xlen];
            app.UIAxes.XDir = 'reverse';
            app.UIAxes.YLim = [0 3.5];
                % Initial plot is all zeros
            app.PlotLine = plot(app.UIAxes,0:app.xlen,zeros(1, app.xlen+1));
            % Timers
            app.Timer = timer( ...
                "ExecutionMode", 'fixedRate', ...
                'Period', 60, ...
                'BusyMode', 'queue', ...
                'StartDelay', 0.1, ...
                'TimerFcn', @app.TimerFcn);
            stop(app.Timer);
            % Serial port device
            app.serialDevice3 = serialport('COM5', 921600);
            configureTerminator(app.serialDevice3, 'LF');
            configureCallback(app.serialDevice3, 'byte', 1, @app.updateRPiStatus);

            % Check raspberry pi availability
                % Create tcp client
            try
                client = tcpclient('localhost', 30000, 'Timeout', 30);
                app.RPiAvailable = writeread(client, ['check','|', '']);
                if strcmp(app.RPiAvailable, '0') % RPi is available 
                    app.ConnectRPi.Enable = 'on';
                end
            catch me
                app.SystemInfo.Text = me.message;
                app.ConnectLabel.Text = 'No se puedo iniciar comuniación TCP';
                return;
            end
        end

        % Value changed function: ConnectRPi
        function ConnectRPiValueChanged(app, event)
            value = app.ConnectRPi.Value;
            % Check if PLC is running
            if strcmp(app.PLCRunning, '1')
                app.ConnectLabel.Text = 'Programa PLC está corriendo';
                app.ConnectRPi.Value = ~value;
                return
            end
            % Create tcp client
            try
                app.ConnectLabel.Text = 'Iniciando comunicación TCP...';
                client = tcpclient('localhost', 30000, 'Timeout', 30);
                pause(0.5);
            catch me
                app.SystemInfo.Text = me.message;
                app.ConnectLabel.Text = 'No se puedo iniciar comuniación TCP';
                app.ConnectRPi.Value = ~value;
                return;
            end
            % Connect to raspberry pi
            if value
                % raspberry pi is available
                if strcmp(app.RPiAvailable, '0')
                    try 
                        app.ConnectLabel.Text = 'Conectando RPi...';
                        app.RPiConnection = writeread(client, ['connect', '|', app.user]);
                        pause(0.5);
                    catch me
                        app.SystemInfo.Text = me.message;
                        app.ConnectLabel.Text = "No se pudo enviar comando para conectar RPi";
                        app.ConnectRPi.Value = ~value;
                        return;
                    end
                    % User is connected to RPi
                    if strcmp(app.RPiConnection, '1')
                        app.ConnectRPi.BackgroundColor = [0.95,0.25,0.25];
                        app.ConnectRPi.Text = 'Desconectar RPi';
                        app.ConnectLabel.Text = '';
                        % Create tcp client
                        app.tcpClient2 = tcpclient('localhost', app.tcpPort2, 'Timeout', 30);
                        % Create serial ports 
                        app.tcpClient1 = tcpclient('localhost', app.tcpPort1, 'Timeout', 30);
                        configureTerminator(app.tcpClient1, 'LF');
                        % Start timer
                        start(app.Timer);
                    else
                        app.ConnectLabel.Text = "No se pudo conectar RPi";
                        app.ConnectRPi.Value = ~value;
                        return;
                    end
                % raspberry pi is not available
                else
                    app.ConnectLabel.Text = "RPi no está disponible";
                    app.ConnectRPi.Value = ~value;
                end
            % Disconnect from raspberry pi
            else
                try
                    app.ConnectLabel.Text = 'Desconectando...';
                    app.RPiConnection = writeread(client, ['disconnect', '|', app.user]);
                    pause(0.5);
                catch me
                    app.ConnectLabel.Text = "No se puedo enviar comando de desconexión";
                    app.SystemInfo.Text = me.message;
                    return;
                end
                app.myraspi = []; % delete raspberry pi object
                app.tcpClient1 = []; 
                app.tcpClient2 = [];
                app.ConnectRPi.BackgroundColor = [0.39,0.83,0.07];
                app.ConnectRPi.Text = 'Conectar RPi';
                app.ConnectLabel.Text = '';
                app.IniciarPLCButton.Enable = 'off';
                stop(app.Timer); % stop timer
            end
        end

        % Button pushed function: UploadButton
        function UploadButtonPushed(app, event)
            app.FileLabel.Text = '';
            [app.uploadedFile, app.uploadedPath] = uigetfile();
            if ischar(app.uploadedFile)
                app.FileLabel.Text = strcat(app.uploadedFile, ...
                    ' Archivo cargado correctamente');
            else
                app.IniciarPLCButton.Enable = "off";
            end
        end

        % Value changed function: IniciarPLCButton
        function IniciarPLCButtonValueChanged(app, event)
            value = app.IniciarPLCButton.Value;
            % Check raspberry pi connection
            if strcmp(app.RPiConnection, '0')
                app.PLCLabel.Text = "No estás conectado a la RPi";
                app.IniciarPLCButton.Value = ~value;
                return
            end
            % Check if file has been sent
            if strcmp(app.FileSent, '0')
                app.PLCLabel.Text = "No se ha enviado ningún archivo";
                app.IniciarPLCButton.Value = ~value;
                return
            end
            % Initialize PLC program
            if value
                try
                    app.PLCLabel.Text = 'Iniciando PLC...';
                    app.PLCRunning = writeread(app.tcpClient1, ['start', '|', '-']);
                catch me
                    app.SystemInfo.Text = me.message;
                    app.PLCLabel.Text = "No fue posible enviar comando para iniciar PLC";
                    app.IniciarPLCButton.Value = ~value;
                    return
                end
                % PLC is running
                if strcmp(app.PLCRunning, '1')
                    % Clear figure
                    app.PlotLine.YData = zeros(1, app.xlen+1);
                    app.led1Data = zeros(1, app.xlen+1);
                    app.led2Data = zeros(1, app.xlen+1);
                    app.IniciarPLCButton.BackgroundColor = [0.95,0.25,0.25];
                    app.IniciarPLCButton.Text = 'Stop PCL';
                    app.PLCLabel.Text = '';
                    app.enableControlButtons('on');
                    writeline(app.tcpClient1, 'running|-');
                    % Configure serial port callback
                    try
                        configureCallback(app.tcpClient2, 'byte', 1, @app.readSystemVariables);
                    catch me
                        app.SystemInfo.Text = me.message;
                    end
                % PLC is not running
                else 
                    app.IniciarPLCButton.Text = 'Iniciar PLC';
                    app.IniciarPLCButton.Value = ~value;
                    app.PLCLabel.Text = "No se pudo iniciar programa PLC";
                end
            % Stopping PLC program
            else
                app.PLCLabel.Text = 'Deteniendo programa PLC...';
                configureCallback(app.tcpClient2, 'off'); % shut down serial port callback             
                app.PLCRunning = writeread(app.tcpClient1, ['stop', '|', pwd]);
                % PLC program stopped
                if strcmp(app.PLCRunning, '0')
                    app.IniciarPLCButton.BackgroundColor = [0.39,0.83,0.07];
                    app.IniciarPLCButton.Text = 'Iniciar PLC';
                    app.updateControlLEDs({[0.96,0.96,0.96], [0.96,0.96,0.96], [0.96,0.96,0.96]});
                    app.enableControlButtons('off');
                    app.PLCLabel.Text = '';
                % PLC program couldn't be stopped
                else
                    app.PLCLabel.Text = "No se pudo detener programa PLC";
                    app.IniciarPLCButton.Value = ~value;
                    configureCallback(app.tcpClient2, 'byte', 1, @app.readSystemVariables);
                end
            end
        end

        % Callback function
        function KnobValueChanged(app, event)
                
        end

        % Button pushed function: EmergButton
        function EmergButtonPushed(app, event)
            app.updateControlLEDs({[0.96,0.96,0.96], [0.96,0.96,0.96], [0.95,0.25,0.25]});
            write(app.tcpClient2, [0, 0, 1], 'uint8');
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            app.updateControlLEDs({[0.39,0.83,0.07], [0.96,0.96,0.96], [0.96,0.96,0.96]});
            write(app.tcpClient2, [1, 0, 0], 'uint8');
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            app.updateControlLEDs({[0.96,0.96,0.96], [1.00,0.41,0.16], [0.96,0.96,0.96]});
            write(app.tcpClient2, [0, 1, 0], 'uint8');
        end

        % Callback function
        function CheckConnectionButtonPushed(app, event)

        end

        % Button pushed function: SendFileButton
        function SendFileButtonPushed(app, event)
            % Check if user is connected to raspberry pi
            if strcmp(app.RPiConnection, '0')
                app.FileLabel.Text = "No estás conectado a la RPi";
                return
            end
            % Check if file has been sent
            if ~ischar(app.uploadedFile)
                app.FileLabel.Text = "No se ha cargado ningún archivo";
                return
            end
            % Check if PLC is running 
            if strcmp(app.PLCRunning, '1')
                app.FileLabel.Text = 'Programa PLC está corriendo';
                return
            end
            % Enviar programa PLC a la raspberry pi
            try
                app.FileLabel.Text = 'Enviando programa PLC...';
                app.FileSent = writeread(app.tcpClient1, [fullfile(app.uploadedPath, app.uploadedFile), '|', '-']);
                app.PLCLabel.Text = '';
                app.FileLabel.Text = '';
                if strcmp(app.FileSent, '1')
                    app.IniciarPLCButton.Enable = "on";
                end
            catch me
                app.FileLabel.Text = "No se puedo enviar programa PLC";
                app.SystemInfo.Text = me.message;
                return
            end
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            app.selectedVar = app.VariableDropDown.Value;
        end

        % Button pushed function: DownloadButton
        function DownloadButtonPushed(app, event)
            try
            data = readmatrix(fullfile(pwd, 'results.xlsx'));
            [file, path] = uiputfile('download.xlsx');
            resultFilePath = fullfile(path, file);
            writematrix(data, resultFilePath);
            catch me
                app.SystemInfo.Text = me.message;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Variables del sistema')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.IniciarPLCButton = uibutton(app.UIFigure, 'state');
            app.IniciarPLCButton.ValueChangedFcn = createCallbackFcn(app, @IniciarPLCButtonValueChanged, true);
            app.IniciarPLCButton.Enable = 'off';
            app.IniciarPLCButton.Text = 'Iniciar PLC';
            app.PLCLabel = uilabel(app.UIFigure);
            app.PLCLabel.Text = '';
            app.ConnectLabel = uilabel(app.UIFigure);
            app.ConnectLabel.Text = '';
            app.ConnectRPi = uibutton(app.UIFigure, 'state');
            app.ConnectRPi.ValueChangedFcn = createCallbackFcn(app, @ConnectRPiValueChanged, true);
            app.ConnectRPi.Enable = 'off';
            app.ConnectRPi.Text = 'Conectar RPi';
            app.DownloadButton = uibutton(app.UIFigure, 'push');
            app.DownloadButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadButtonPushed, true);
            app.DownloadButton.Icon = fullfile(pathToMLAPP, 'excel_download.png');
            app.DownloadButton.Text = '';
            app.UploadButton = uibutton(app.UIFigure, 'push');
            app.UploadButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadButton.Icon = fullfile(pathToMLAPP, 'upload.png');
            app.UploadButton.Text = '';
            app.Panel = uipanel(app.UIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'MaIE.png');
            app.LaboratorioRemotoMaIELabel = uilabel(app.Panel);
            app.LaboratorioRemotoMaIELabel.Text = {'Laboratorio '; 'Remoto MaIE'};
            app.ControlpanelPanel = uipanel(app.UIFigure);
            app.ControlpanelPanel.Title = 'Control panel';
            app.EmergButton = uibutton(app.ControlpanelPanel, 'push');
            app.EmergButton.ButtonPushedFcn = createCallbackFcn(app, @EmergButtonPushed, true);
            app.EmergButton.Enable = 'off';
            app.EmergButton.Text = 'EMERGENCY';
            app.StartButton = uibutton(app.ControlpanelPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'START';
            app.StopButton = uibutton(app.ControlpanelPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Enable = 'off';
            app.StopButton.Text = 'STOP';
            app.UserInfo = uilabel(app.UIFigure);
            app.UserInfo.Text = '';
            app.SystemInfo = uilabel(app.UIFigure);
            app.SystemInfo.Text = '';
            app.SendFileButton = uibutton(app.UIFigure, 'push');
            app.SendFileButton.ButtonPushedFcn = createCallbackFcn(app, @SendFileButtonPushed, true);
            app.SendFileButton.Icon = fullfile(pathToMLAPP, 'send_file.png');
            app.SendFileButton.Text = '';
            app.FileLabel = uilabel(app.UIFigure);
            app.FileLabel.Text = '';
            app.LED1Lamp = uilamp(app.UIFigure);
            app.LED2Lamp = uilamp(app.UIFigure);
            app.VariableDropDownLabel = uilabel(app.UIFigure);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.UIFigure);
            app.VariableDropDown.Items = {'LED 1', 'LED 2'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'LED 1';
            app.LED1Label = uilabel(app.UIFigure);
            app.LED1Label.Text = 'LED 1';
            app.LED2Label = uilabel(app.UIFigure);
            app.LED2Label.Text = 'LED 2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = two_tank_lab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end